classdef GUI_Create_Cytometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        CancelButton   matlab.ui.control.Button
        SaveButton     matlab.ui.control.Button
        CytometerName  matlab.ui.control.EditField
        PleaseenterauniquecytometerIDEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        callingapp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallApp)
            app.callingapp = CallApp;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            path = fullfile(getpref('QbPASS_Beta','FileDirectory'),app.CytometerName.Value);
            if ~isfolder(path)
                mkdir(path)
                UpdateCytometers(app.callingapp)
                delete(app)
            else
                uialert(app.UIFigure,'Cytometer ID already exists!','Error')
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PleaseenterauniquecytometerIDEditFieldLabel = uilabel(app.UIFigure);
            app.PleaseenterauniquecytometerIDEditFieldLabel.Text = 'Please enter a unique cytometer ID';
            app.CytometerName = uieditfield(app.UIFigure, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Create_Cytometer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end