classdef GUI_Master_QbPASS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QbPASS_UI                  matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        PreferencesMenu            matlab.ui.container.Menu
        ReprocessMenu              matlab.ui.container.Menu
        AddCytometerPanel          matlab.ui.container.Panel
        CancelCytometerButton      matlab.ui.control.Button
        AddCytometerButton         matlab.ui.control.Button
        CytometerEditField         matlab.ui.control.EditField
        MainPanel                  matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        Dataset_Remove             matlab.ui.control.Button
        Dataset_Add                matlab.ui.control.Button
        Cytoemter_Remove           matlab.ui.control.Button
        Cytometer_Add              matlab.ui.control.Button
        SelectedDatasetDropDown    matlab.ui.control.DropDown
        SelectedCytometerDropDown  matlab.ui.control.DropDown
    end


    properties (Access = private)
        % Description
    end

    properties (Access = public)
        version % Description
        ImportSelpath
        PrefName
    end

    methods (Access = private)

        
        function ChangePanel(app,name)

            AllPanels = {'MainPanel','AddCytometerPanel'};

            for i = 1:numel(AllPanels)
                app.(AllPanels{i}).Position = [-1000,-1000,390,450];
            end

            app.(name).Position = [0,0,390,450];
            
        end
    end

    methods (Access = public)


        function UpdateCytometers(app)
            % Get files within the QbPASS file directory
            files = dir(getpref(app.PrefName,'FileDirectory'));
            % Get a logical vector that tells which is a directory.
            dirFlags = [files.isdir];
            % Extract only those that are directories.
            subFolders = {files(dirFlags).name};
            % subfolders to exclude
            Exclude = {'.','..','QbPASS Outputs'};
            % Assign the folder names to the Cytometer ID dropdown
            app.SelectedCytometerDropDown.Items = subFolders(~contains(subFolders,Exclude));
            app.SelectedCytometerDropDown.ItemsData = 1:numel(app.SelectedCytometerDropDown.Items);
        end

        function UpdateDatasets(app,Dataset)
            if ~isempty(app.SelectedCytometerDropDown.Items)
                SelectedCytometerDropDownValueChanged(app,@SelectedCytometerDropDownValueChanged)

                app.SelectedDatasetDropDown.Value = Dataset;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, prefname, version)
            % software version
            app.version = version;
            app.PrefName = prefname;

            ChangePanel(app,'MainPanel')

            % try
                % check preferences ted
                QbPASS_Preference_Check(app)
            % catch
            %     uialert(app.QbPASS_UI,'Preference Check Failed','Start-up error')
            % end

            try
                % check directory is valid
                QbPASS_Folder_Check(app)
            catch
                uialert(app.QbPASS_UI,'Folder Check Failed','Start-up error')
            end

            try
                % index cytometers from directory
                UpdateCytometers(app)
            catch
                uialert(app.QbPASS_UI,'Cytometer Check Failed','Start-up error')
            end

            try
                UpdateDatasets(app,1)
            catch
                uialert(app.QbPASS_UI,'Dropdown Index Check Failed','Start-up error')
            end
        end

        % Button pushed function: Cytometer_Add
        function Cytometer_AddPushed(app, event)
            ChangePanel(app,'AddCytometerPanel')


        end

        % Button pushed function: Cytoemter_Remove
        function Cytoemter_RemovePushed(app, event)
            selection = uiconfirm(app.QbPASS_UI,'Performing this function will remove emove all associated datasets and outputs associated with this Cytometer ID. Are you sure you wish to continue?','Dataset deletection');
            switch selection
                case 'OK'
                    path = fullfile(getpref(app.PrefName,'FileDirectory'),app.SelectedCytometerDropDown.Items{app.SelectedCytometerDropDown.Value});
                    path2 = fullfile(getpref(app.PrefName,'FileDirectory'),'QbPASS Outputs',app.SelectedCytometerDropDown.Items{app.SelectedCytometerDropDown.Value});
                    if isfolder(path)
                        rmdir(path,'s')
                    end
                    if isfolder(path2)
                        rmdir(path2,'s')
                    end
                    UpdateCytometers(app)
            end
        end

        % Button pushed function: Dataset_Add
        function Dataset_AddButtonPushed(app, event)
            if isempty(app.SelectedCytometerDropDown.Value)
                uialert(app.QbPASS_UI, 'Please add a cytometer','Error adding dataset')
            else
                [proceed] = QbPASS_Import_FCS_Files(app);
                if proceed == true
                    QbPASS_Folder_Check(app)

                    d = uiprogressdlg(app.QbPASS_UI,'Title','Please Wait','Message',['Generating plots'],'Indeterminate','on');
                    QbPASS_Output_Plots(app)
                    close(d)
                end
               
            end
        end

        % Button pushed function: Dataset_Remove
        function Dataset_RemoveButtonPushed(app, event)
            Datasets = dir(fullfile(getpref(app.PrefName,'FileDirectory'),app.SelectedCytometerDropDown.Items{app.SelectedCytometerDropDown.Value},'*.mat'));

            if ~isempty(Datasets)
                % convert datanum to datastr for each filename
                for i = 1:size(Datasets,1)
                    temp{i} = [datestr(str2double(replace(Datasets(i).name,{'D_','.mat'},'')), 'yyyy-mm-dd'), ' Acquisition'];
                end

                deleteInd = strcmp(temp, app.SelectedDatasetDropDown.Items{app.SelectedDatasetDropDown.Value});
                delete(fullfile(Datasets(deleteInd).folder, Datasets(deleteInd).name))
                SelectedCytometerDropDownValueChanged(app,@SelectedCytometerDropDownValueChanged)

            end

        end

        % Value changed function: SelectedCytometerDropDown
        function SelectedCytometerDropDownValueChanged(app, event)
            % get datasets within cytometer folder
            Datasets = dir(fullfile(getpref(app.PrefName,'FileDirectory'),app.SelectedCytometerDropDown.Items{app.SelectedCytometerDropDown.Value},'*mat'));

            if ~isempty(Datasets)
                % convert datanum to datastr for each filename
                for i = 1:size(Datasets,1)
                    temp{i} = [datestr(str2double(replace(Datasets(i).name,{'D_','.mat'},'')), 'yyyy-mm-dd'), ' Acquisition'];
                end
                % add datasets to dropdown list
                app.SelectedDatasetDropDown.Items = temp;
                app.SelectedDatasetDropDown.ItemsData = 1:numel(temp);
            else
                app.SelectedDatasetDropDown.Items = {''};
                app.SelectedDatasetDropDown.ItemsData = 1;
            end
        end

        % Menu selected function: ReprocessMenu
        function ReprocessButtonPushed(app, event)
            try

                d = uiprogressdlg(app.QbPASS_UI,'Title','Please Wait','Message',['Generating plots'],'Indeterminate','on');
                QbPASS_Output_Plots(app)
                close(d)
            catch
                uialert(app.QbPASS_UI,'QC plot output failed','QC plot')

            end
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            Master_Pref_v1(app.version, app.PrefName)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            Master_QbAbout()
        end

        % Button pushed function: CancelCytometerButton
        function CancelCytometerButtonPushed(app, event)
                          
            ChangePanel(app,'MainPanel')

        end

        % Button pushed function: AddCytometerButton
        function AddCytometerButtonPushed(app, event)
            try

                path = fullfile(getpref(app.PrefName,'FileDirectory'),app.CytometerEditField.Value);
                if ~isfolder(path)
                    mkdir(path)
                    UpdateCytometers(app)
                else
                    uialert(app.QbPASS_UI,'Cytometer ID already exists!','Error')
                end
                ChangePanel(app,'MainPanel')
            catch
                uialert(app.QbPASS_UI,'Failed to create new cytometer','Add cytometer')

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QbPASS_UI = uifigure('Visible', 'off');
            app.QbPASS_UI.Name = 'QbPASS';
            app.QbPASS_UI.Icon = 'QbLogo.png';
            app.Menu = uimenu(app.QbPASS_UI);
            app.Menu.Text = 'Menu';
            app.AboutMenu = uimenu(app.Menu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.PreferencesMenu = uimenu(app.Menu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.ReprocessMenu = uimenu(app.Menu);
            app.ReprocessMenu.MenuSelectedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessMenu.Text = 'Re-process';
            app.MainPanel = uipanel(app.QbPASS_UI);
            app.SelectedCytometerDropDown = uidropdown(app.MainPanel);
            app.SelectedCytometerDropDown.Items = {};
            app.SelectedCytometerDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedCytometerDropDownValueChanged, true);
            app.SelectedCytometerDropDown.Placeholder = 'Selected Cytometer';
            app.SelectedCytometerDropDown.Value = {};
            app.SelectedDatasetDropDown = uidropdown(app.MainPanel);
            app.SelectedDatasetDropDown.Items = {};
            app.SelectedDatasetDropDown.Placeholder = 'Selected Dataset';
            app.SelectedDatasetDropDown.Value = {};
            app.Cytometer_Add = uibutton(app.MainPanel, 'push');
            app.Cytometer_Add.ButtonPushedFcn = createCallbackFcn(app, @Cytometer_AddPushed, true);
            app.Cytometer_Add.Icon = 'plus-solid.svg';
            app.Cytometer_Add.Text = '';
            app.Cytoemter_Remove = uibutton(app.MainPanel, 'push');
            app.Cytoemter_Remove.ButtonPushedFcn = createCallbackFcn(app, @Cytoemter_RemovePushed, true);
            app.Cytoemter_Remove.Icon = 'minus-solid.svg';
            app.Cytoemter_Remove.Text = '';
            app.Dataset_Add = uibutton(app.MainPanel, 'push');
            app.Dataset_Add.ButtonPushedFcn = createCallbackFcn(app, @Dataset_AddButtonPushed, true);
            app.Dataset_Add.Icon = 'plus-solid.svg';
            app.Dataset_Add.Text = '';
            app.Dataset_Remove = uibutton(app.MainPanel, 'push');
            app.Dataset_Remove.ButtonPushedFcn = createCallbackFcn(app, @Dataset_RemoveButtonPushed, true);
            app.Dataset_Remove.Icon = 'minus-solid.svg';
            app.Dataset_Remove.Text = '';
            app.Image = uiimage(app.MainPanel);
            app.Image.ImageSource = 'QbLogo.png';
            app.AddCytometerPanel = uipanel(app.QbPASS_UI);
            app.CytometerEditField = uieditfield(app.AddCytometerPanel, 'text');
            app.CytometerEditField.CharacterLimits = [0 50];
            app.CytometerEditField.Placeholder = 'Cytometer Name';
            app.AddCytometerButton = uibutton(app.AddCytometerPanel, 'push');
            app.AddCytometerButton.ButtonPushedFcn = createCallbackFcn(app, @AddCytometerButtonPushed, true);
            app.AddCytometerButton.Text = 'Save';
            app.CancelCytometerButton = uibutton(app.AddCytometerPanel, 'push');
            app.CancelCytometerButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCytometerButtonPushed, true);
            app.CancelCytometerButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.QbPASS_UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Master_QbPASS(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.QbPASS_UI)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.QbPASS_UI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QbPASS_UI)
        end
    end
end