classdef Master_Pref_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QbPASSPref                     matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        VoltageNormalizationEditField  matlab.ui.control.NumericEditField
        VoltageNormalizationEditFieldLabel  matlab.ui.control.Label
        CVThresholdEditField           matlab.ui.control.NumericEditField
        CVThresholdEditFieldLabel      matlab.ui.control.Label
        DeveloperaccessDropDown        matlab.ui.control.DropDown
        DeveloperaccessDropDownLabel   matlab.ui.control.Label
        ResetallpreferencevaluesLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        DataProcessingLabel            matlab.ui.control.Label
        MaxChannelThresholdEditField   matlab.ui.control.NumericEditField
        MaxChannelThresholdEditFieldLabel  matlab.ui.control.Label
        GradientThresholdEditField     matlab.ui.control.NumericEditField
        GradientThresholdEditFieldLabel  matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        SoftwareVersionEditField       matlab.ui.control.NumericEditField
        SoftwareVersionEditFieldLabel  matlab.ui.control.Label
        DirectoryLocation              matlab.ui.control.EditField
        DirectoryLocationLabel         matlab.ui.control.Label
        CloseButton                    matlab.ui.control.StateButton
        General_DefaultButton          matlab.ui.control.StateButton
        General_SaveButton             matlab.ui.control.StateButton
        FiguresTab                     matlab.ui.container.Tab
        Figures_SaveButton             matlab.ui.control.StateButton
        Figures_DefaultButton          matlab.ui.control.StateButton
        Figures_CancelButton           matlab.ui.control.StateButton
        TabGroup2                      matlab.ui.container.TabGroup
        GlobalPlotSettingsTab          matlab.ui.container.Tab
        OutputFormatDropDown           matlab.ui.control.DropDown
        OutputFormatDropDownLabel      matlab.ui.control.Label
        TitleFontWeightDropDown        matlab.ui.control.DropDown
        AxesFontWeightDropDown         matlab.ui.control.DropDown
        LabelFontWeightDropDown        matlab.ui.control.DropDown
        ColorSelection                 matlab.ui.control.StateButton
        NoFigureBackgroundCheckBox     matlab.ui.control.CheckBox
        TitleFontSizeEditField         matlab.ui.control.NumericEditField
        TitleFontSizeEditFieldLabel    matlab.ui.control.Label
        AxesFontSizeEditField          matlab.ui.control.NumericEditField
        AxesFontSizeEditFieldLabel     matlab.ui.control.Label
        LabelFontSizeEditField         matlab.ui.control.NumericEditField
        LabelFontSizeEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        version % Description
        prefname
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, version, prefname)
            app.version = version;
            app.prefname = prefname;
            % General
            app.SoftwareVersionEditField.Value = getpref(app.prefname,'version');
            app.DirectoryLocation.Value = getpref(app.prefname,'FileDirectory');
            app.GradientThresholdEditField.Value = getpref(app.prefname,'Threshold_Grad');
            app.MaxChannelThresholdEditField.Value = getpref(app.prefname,'Threshold_Channel');
            app.CVThresholdEditField.Value = getpref(app.prefname,'Threshold_CV');
            app.VoltageNormalizationEditField.Value = getpref(app.prefname,'Threshold_Voltage');

            if strcmp('default',getpref(app.prefname, 'FigLabel_FontSize')) == 0
                app.LabelFontSizeEditField.Value = getpref(app.prefname, 'FigLabel_FontSize');
            end
            app.LabelFontWeightDropDown.Value = getpref(app.prefname, 'FigLabel_FontWeight');
            
            if strcmp('default',getpref(app.prefname, 'FigAxes_FontSize')) == 0
                app.AxesFontSizeEditField.Value = getpref(app.prefname, 'FigAxes_FontSize');
            end
            app.AxesFontWeightDropDown.Value = getpref(app.prefname, 'FigAxes_FontWeight');
            
            if strcmp('default', getpref(app.prefname, 'FigTitle_FontSize')) == 0
                app.TitleFontSizeEditField.Value = getpref(app.prefname, 'FigTitle_FontSize');
            end
            app.TitleFontWeightDropDown.Value = getpref(app.prefname, 'FigTitle_FontWeight');
            
%             getpref('QbPASS', 'Fig_Tick_FontSize');
%             getpref('QbPASS', 'Fig_Tick_FontWeight');
            
            app.DeveloperaccessDropDown.Value = getpref(app.prefname,'DeveloperAccess');


        end

        % Value changed function: General_SaveButton
        function General_SaveButtonValueChanged(app, event)
            % General
            setpref(app.prefname,'FileDirectory', app.DirectoryLocation.Value);
            setpref(app.prefname,'Threshold_Grad', app.GradientThresholdEditField.Value);
            setpref(app.prefname,'Threshold_Channel', app.MaxChannelThresholdEditField.Value);
            setpref(app.prefname,'Threshold_CV', app.CVThresholdEditField.Value);
            setpref(app.prefname,'Threshold_Voltage', app.VoltageNormalizationEditField.Value);
            
            app.General_SaveButton.Value = 0;
            
        end

        % Value changed function: CloseButton, Figures_CancelButton
        function CloseButtonValueChanged(app, event)
            close(app.QbPASSPref)
        end

        % Value changed function: General_DefaultButton
        function General_DefaultButtonValueChanged(app, event)
            app.GradientThresholdEditField.Value = 50;
            app.MaxChannelThresholdEditField.Value = 150000;
            app.CVThresholdEditField.Value = 3.16;
            app.VoltageNormalizationEditField.Value = 500;

        end

        % Value changed function: Figures_DefaultButton
        function Figures_DefaultButtonValueChanged(app, event)
            
            % General
            app.LabelFontSizeEditField.Value = 14;
            app.AxesFontSizeEditField.Value = 12;
            app.TitleFontSizeEditField.Value = 14;
            
            app.LabelFontWeightDropDown.Value = 'bold';
            app.AxesFontWeightDropDown.Value = 'normal';
            app.TitleFontWeightDropDown.Value = 'bold';
            
            app.NoFigureBackgroundCheckBox.Value = 1;
            
            app.Figures_DefaultButton.Value = 0;
            
        end

        % Value changed function: Figures_SaveButton
        function Figures_SaveButtonValueChanged(app, event)
            setpref(app.prefname,'FigLabel_FontSize', app.LabelFontSizeEditField.Value)
            setpref(app.prefname,'FigLabel_FontWeight', app.LabelFontWeightDropDown.Value)
            setpref(app.prefname,'FigTitle_FontSize', app.TitleFontSizeEditField.Value)
            setpref(app.prefname,'FigTitle_FontWeight', app.TitleFontWeightDropDown.Value)
            setpref(app.prefname,'FigAxes_FontSize', app.AxesFontSizeEditField.Value)
            setpref(app.prefname,'FigAxes_FontWeight', app.AxesFontWeightDropDown.Value)
            
            setpref(app.prefname, 'outputFormat', app.OutputFormatDropDown.Value)
            
            app.Figures_SaveButton.Value = 0;
            
        end

        % Value changed function: ColorSelection
        function ColorSelectionValueChanged(app, event)
            c = uisetcolor([1 1 0],'Select a color');
            app.ColorSelection.BackgroundColor = c;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            selpath = uigetdir;
            if selpath == 0
            else
                setpref(app.prefname,'FileDirectory',selpath)
            end
            app.DirectoryLocation.Value = getpref('QbPASS','FileDirectory');
            FileDirectoryCheck()
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            selection = uiconfirm(app.QbPASSPref,'Are you sure you wish to reset all QbPASS preferences? This operation will remove all presaved preferencess including the directory root which will need to be specified again. This operation cannot be undone.','Confirm Reset',...
                'Icon','warning');
            switch selection
                case 'OK'
                    rmpref(app.prefname);
                    QbPASS_Preference_Check_v2(app.version, app.QbPASSPref);
            end
            
        end

        % Value changed function: DeveloperaccessDropDown
        function DeveloperaccessDropDownValueChanged(app, event)
            setpref(app.prefname,'DeveloperAccess',app.DeveloperaccessDropDown.Value)
        end

        % Value changed function: OutputFormatDropDown
        function OutputFormatDropDownValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QbPASSPref = uifigure('Visible', 'off');
            app.QbPASSPref.Name = 'Preferences';
            app.TabGroup = uitabgroup(app.QbPASSPref);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.General_SaveButton = uibutton(app.GeneralTab, 'state');
            app.General_SaveButton.ValueChangedFcn = createCallbackFcn(app, @General_SaveButtonValueChanged, true);
            app.General_SaveButton.Text = 'Save';
            app.General_DefaultButton = uibutton(app.GeneralTab, 'state');
            app.General_DefaultButton.ValueChangedFcn = createCallbackFcn(app, @General_DefaultButtonValueChanged, true);
            app.General_DefaultButton.Text = 'Default';
            app.CloseButton = uibutton(app.GeneralTab, 'state');
            app.CloseButton.ValueChangedFcn = createCallbackFcn(app, @CloseButtonValueChanged, true);
            app.CloseButton.Text = 'Close';
            app.DirectoryLocationLabel = uilabel(app.GeneralTab);
            app.DirectoryLocationLabel.Text = 'Directory Location';
            app.DirectoryLocation = uieditfield(app.GeneralTab, 'text');
            app.DirectoryLocation.Editable = 'off';
            app.SoftwareVersionEditFieldLabel = uilabel(app.GeneralTab);
            app.SoftwareVersionEditFieldLabel.Text = 'Software Version';
            app.SoftwareVersionEditField = uieditfield(app.GeneralTab, 'numeric');
            app.SoftwareVersionEditField.Editable = 'off';
            app.UpdateButton = uibutton(app.GeneralTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.GradientThresholdEditFieldLabel = uilabel(app.GeneralTab);
            app.GradientThresholdEditFieldLabel.Text = 'Gradient Threshold (%)';
            app.GradientThresholdEditField = uieditfield(app.GeneralTab, 'numeric');
            app.MaxChannelThresholdEditFieldLabel = uilabel(app.GeneralTab);
            app.MaxChannelThresholdEditFieldLabel.Text = 'Max Channel Threshold (%)';
            app.MaxChannelThresholdEditField = uieditfield(app.GeneralTab, 'numeric');
            app.MaxChannelThresholdEditField.Limits = [0.001 100];
            app.MaxChannelThresholdEditField.Value = 0.001;
            app.DataProcessingLabel = uilabel(app.GeneralTab);
            app.DataProcessingLabel.Text = 'Data Processing';
            app.ResetButton = uibutton(app.GeneralTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResetallpreferencevaluesLabel = uilabel(app.GeneralTab);
            app.ResetallpreferencevaluesLabel.Text = 'Reset all preference values';
            app.DeveloperaccessDropDownLabel = uilabel(app.GeneralTab);
            app.DeveloperaccessDropDownLabel.Text = 'Developer access';
            app.DeveloperaccessDropDown = uidropdown(app.GeneralTab);
            app.DeveloperaccessDropDown.Items = {'off', 'on'};
            app.DeveloperaccessDropDown.ValueChangedFcn = createCallbackFcn(app, @DeveloperaccessDropDownValueChanged, true);
            app.DeveloperaccessDropDown.Value = 'off';
            app.CVThresholdEditFieldLabel = uilabel(app.GeneralTab);
            app.CVThresholdEditFieldLabel.Text = 'CV Threshold (%)';
            app.CVThresholdEditField = uieditfield(app.GeneralTab, 'numeric');
            app.VoltageNormalizationEditFieldLabel = uilabel(app.GeneralTab);
            app.VoltageNormalizationEditFieldLabel.Text = 'Voltage Normalization (%)';
            app.VoltageNormalizationEditField = uieditfield(app.GeneralTab, 'numeric');
            app.VoltageNormalizationEditField.Limits = [100 800];
            app.VoltageNormalizationEditField.Value = 100;
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            app.TabGroup2 = uitabgroup(app.FiguresTab);
            app.GlobalPlotSettingsTab = uitab(app.TabGroup2);
            app.GlobalPlotSettingsTab.Title = 'Global Plot Settings';
            app.LabelFontSizeEditFieldLabel = uilabel(app.GlobalPlotSettingsTab);
            app.LabelFontSizeEditFieldLabel.Text = 'Label Font Size';
            app.LabelFontSizeEditField = uieditfield(app.GlobalPlotSettingsTab, 'numeric');
            app.LabelFontSizeEditField.Limits = [1 20];
            app.LabelFontSizeEditField.Value = 14;
            app.AxesFontSizeEditFieldLabel = uilabel(app.GlobalPlotSettingsTab);
            app.AxesFontSizeEditFieldLabel.Text = 'Axes Font Size';
            app.AxesFontSizeEditField = uieditfield(app.GlobalPlotSettingsTab, 'numeric');
            app.AxesFontSizeEditField.Limits = [1 20];
            app.AxesFontSizeEditField.Value = 12;
            app.TitleFontSizeEditFieldLabel = uilabel(app.GlobalPlotSettingsTab);
            app.TitleFontSizeEditFieldLabel.Text = 'Title Font Size';
            app.TitleFontSizeEditField = uieditfield(app.GlobalPlotSettingsTab, 'numeric');
            app.TitleFontSizeEditField.Limits = [1 20];
            app.TitleFontSizeEditField.Value = 14;
            app.NoFigureBackgroundCheckBox = uicheckbox(app.GlobalPlotSettingsTab);
            app.NoFigureBackgroundCheckBox.Text = 'No Figure Background';
            app.NoFigureBackgroundCheckBox.Value = true;
            app.ColorSelection = uibutton(app.GlobalPlotSettingsTab, 'state');
            app.ColorSelection.ValueChangedFcn = createCallbackFcn(app, @ColorSelectionValueChanged, true);
            app.ColorSelection.Text = '';
            app.LabelFontWeightDropDown = uidropdown(app.GlobalPlotSettingsTab);
            app.LabelFontWeightDropDown.Items = {'normal', 'bold'};
            app.LabelFontWeightDropDown.Value = 'bold';
            app.AxesFontWeightDropDown = uidropdown(app.GlobalPlotSettingsTab);
            app.AxesFontWeightDropDown.Items = {'normal', 'bold'};
            app.AxesFontWeightDropDown.Value = 'normal';
            app.TitleFontWeightDropDown = uidropdown(app.GlobalPlotSettingsTab);
            app.TitleFontWeightDropDown.Items = {'normal', 'bold'};
            app.TitleFontWeightDropDown.Value = 'bold';
            app.OutputFormatDropDownLabel = uilabel(app.GlobalPlotSettingsTab);
            app.OutputFormatDropDownLabel.Text = 'Output Format';
            app.OutputFormatDropDown = uidropdown(app.GlobalPlotSettingsTab);
            app.OutputFormatDropDown.Items = {'ps', 'jpeg'};
            app.OutputFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputFormatDropDownValueChanged, true);
            app.OutputFormatDropDown.Value = 'ps';
            app.Figures_CancelButton = uibutton(app.FiguresTab, 'state');
            app.Figures_CancelButton.ValueChangedFcn = createCallbackFcn(app, @CloseButtonValueChanged, true);
            app.Figures_CancelButton.Text = 'Close';
            app.Figures_DefaultButton = uibutton(app.FiguresTab, 'state');
            app.Figures_DefaultButton.ValueChangedFcn = createCallbackFcn(app, @Figures_DefaultButtonValueChanged, true);
            app.Figures_DefaultButton.Text = 'Default';
            app.Figures_SaveButton = uibutton(app.FiguresTab, 'state');
            app.Figures_SaveButton.ValueChangedFcn = createCallbackFcn(app, @Figures_SaveButtonValueChanged, true);
            app.Figures_SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.QbPASSPref.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Master_Pref_v1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QbPASSPref)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QbPASSPref)
        end
    end
end