classdef Master_QbAbout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutUIFigure  matlab.ui.Figure
        Label          matlab.ui.control.Label
        Image          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ver = getpref('QbPASS_Beta','version');
            app.Image.ImageSource = '/Users/welshjoa/OneDrive - National Institutes of Health/Research/MATLAB/Software/Fluorescence Qb/Icons/Qb Logo.jpg';
            app.Label.Text = ['QbPASS Software Version: ',num2str(ver),newline,...
                newline,...
                'Algorithms developed by: James C. S. Wood, Wake Forest University', newline,...
                newline,...
                'Software development by: Joshua A. Welsh, NCI, NIH',newline,...
                '                                           James C. S. Wood, Wake Forest University',newline,...
                '                                           Stephen Perfetto, NIAID, NIH', newline,...
                newline,...
                'Software coded by: Joshua A Welsh, NCI, NIH',newline,...
                newline,...
                'References: TBC'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AboutUIFigure = uifigure('Visible', 'off');
            app.AboutUIFigure.Name = 'About';
            app.Image = uiimage(app.AboutUIFigure);
            app.Image.ImageSource = 'QbLogo.png';
            app.Label = uilabel(app.AboutUIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.AboutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Master_QbAbout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AboutUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutUIFigure)
        end
    end
end